classdef ServoControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ServoControlUIFigure     matlab.ui.Figure
        ServoControlKnobLabel    matlab.ui.control.Label
        ServoControlKnob         matlab.ui.control.Knob
        EmbeddedLaboratoryLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        ser % Serial Object
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfind);
            % Create Serial Object at 9600 BaudRate
            app.ser = serial('COM8','BaudRate', 9600);
            % Open Serial Port
            fopen(app.ser);
            value = 0;
            fwrite(app.ser, value);
            
        end

        % Value changed function: ServoControlKnob
        function ServoControlKnobValueChanged(app, event)
            value = app.ServoControlKnob.Value;
            value = round(value,-1);
            app.ServoControlKnob.Value = value;
            fwrite(app.ser, value);
            disp(value)
        end

        % Close request function: ServoControlUIFigure
        function ServoControlUIFigureCloseRequest(app, event)
            fclose(app.ser);
            delete(instrfind);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ServoControlUIFigure = uifigure;
            app.ServoControlUIFigure.Name = 'Servo Control';
            app.ServoControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @ServoControlUIFigureCloseRequest, true);
            setAutoResize(app, app.ServoControlUIFigure, true)
            app.ServoControlKnobLabel = uilabel(app.ServoControlUIFigure);
            app.ServoControlKnobLabel.Text = 'Servo Control';
            app.ServoControlKnob = uiknob(app.ServoControlUIFigure, 'continuous');
            app.ServoControlKnob.Limits = [0 180];
            app.ServoControlKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.ServoControlKnob.ValueChangedFcn = createCallbackFcn(app, @ServoControlKnobValueChanged, true);
            app.ServoControlKnob.MinorTicks = [];
            app.EmbeddedLaboratoryLabel = uilabel(app.ServoControlUIFigure);
            app.EmbeddedLaboratoryLabel.Text = 'Embedded Laboratory';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ServoControl()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ServoControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ServoControlUIFigure)
        end
    end
end